classdef TouchProbeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BWDButton                matlab.ui.control.Button
        FWDButton                matlab.ui.control.Button
        LEFTButton               matlab.ui.control.Button
        RIGHTButton              matlab.ui.control.Button
        UICameraViewer           matlab.ui.control.UIAxes
        xLabel                   matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        zEditField               matlab.ui.control.NumericEditField
        DPostionControllerLabel  matlab.ui.control.Label
        ForceNEditFieldLabel     matlab.ui.control.Label
        ForceNEditField          matlab.ui.control.NumericEditField
        Current3DPostionLabel    matlab.ui.control.Label
        UPButton                 matlab.ui.control.Button
        DOWNButton               matlab.ui.control.Button
        IncrementSpinnerLabel    matlab.ui.control.Label
        IncrementSpinner         matlab.ui.control.Spinner
        OffsetNEditFieldLabel    matlab.ui.control.Label
        OffsetNEditField         matlab.ui.control.NumericEditField
        UIForceVisualizer        matlab.ui.control.UIAxes
        ForceNLabel              matlab.ui.control.Label
        SaveGraphButton          matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ScanButton               matlab.ui.control.Button
        ResolutionSpinnerLabel   matlab.ui.control.Label
        ResolutionSpinner        matlab.ui.control.Spinner
        DScanSettingsLabel       matlab.ui.control.Label
        HeightzEditFieldLabel    matlab.ui.control.Label
        HeightzEditField         matlab.ui.control.NumericEditField
        GetHeightButton          matlab.ui.control.Button
        GetGraphButton           matlab.ui.control.Button
        HeightmmEditFieldLabel   matlab.ui.control.Label
        HeightmmEditField        matlab.ui.control.NumericEditField
        MethodDropDownLabel      matlab.ui.control.Label
        MethodDropDown           matlab.ui.control.DropDown
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global tp;
            
            tp = TouchProbe(app, 'http://169.254.0.3:11311', '169.254.0.3');
            tp.set_point(0, 0, 1.5);
            tp.y = zeros(1, numel(tp.x));
            plot(app.UIForceVisualizer,tp.x,tp.y);
            tp.disp_fsr();
            tp.disp_cam();
        end

        % Button pushed function: BWDButton
        function BWDButtonPushed(app, event)
            global tp;
            x = app.xEditField.Value;
            y = app.yEditField.Value - app.IncrementSpinner.Value;
            z = app.zEditField.Value;
            app.yEditField.Value = y;
            tp.set_point(x, y, z);
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            global tp;
            sensor_data = [];
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            if app.ForceNEditField.Value > 0.00
                tp.set_point(-1, -1, -1);
                pause(0.5);
            end
            z = 0;%app.zEditField.xValue - app.IncrementSpinner.Value;
            tp.set_point(-1, -1, z);
            pause(2);
            sensor_data = [sensor_data, tp.force_value];
            pause(0.2);
            app.zEditField.Value = z;
            app.HeightzEditField.Value = mean(sensor_data);
        end

        % Button pushed function: FWDButton
        function FWDButtonPushed(app, event)
            global tp;
            x = app.xEditField.Value;
            y = app.yEditField.Value + app.IncrementSpinner.Value;
            z = app.zEditField.Value;
            app.yEditField.Value = y;
            tp.set_point(x, y, z);
        end

        % Button pushed function: GetGraphButton
        function GetGraphButtonPushed(app, event)
            global tp;
            max_val = max(tp.data);
            min_val = min(tp.data);
            rgb_ratio = 1 / (max_val - min_val);
            cpy_data = 1 - ((tp.data - min_val) .* rgb_ratio);
            image(app.UIAxes, cpy_data);
        end

        % Button pushed function: GetHeightButton
        function GetHeightButtonPushed(app, event)
            global tp;
            if app.MethodDropDown.Value == "Z-Scan Binary"
                tp.get_z();
            elseif app.MethodDropDown.Value == "Z-Scan Backtrack"
                tp.bt_get_z();
            end
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
            global tp;
            x = app.xEditField.Value - app.IncrementSpinner.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
            app.xEditField.Value = x;
            tp.set_point(x, y, z);
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
            global tp;
            x = app.xEditField.Value + app.IncrementSpinner.Value;
            y = app.yEditField.Value;
            z = app.zEditField.Value;
            app.xEditField.Value = x;
            tp.set_point(x, y, z);
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            fig1 = figure;
            ax = axes;
            ylim([0 20]);
            copyobj(app.UIForceVisualizer.Children,ax);
            saveas( fig1,'result.jpg');
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            global tp;
            if app.MethodDropDown.Value == "Force Scan Discrete"
                img = tp.force_scan(app.ResolutionSpinner.Value);
            elseif app.MethodDropDown.Value == "Force Scan Dragging"
                img = tp.drag_scan(app.ResolutionSpinner.Value);
            elseif app.MethodDropDown.Value == "Z-Scan Binary"
                img = tp.z_scan(app.ResolutionSpinner.Value);
            elseif app.MethodDropDown.Value == "Z-Scan Backtrack"
                img = tp.backtrack_scan(app.ResolutionSpinner.Value);
            end            
            image(app.UIAxes, img);
            tp.set_point(0, 0, 1.5);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            rosshutdown;
            clear;
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            global tp;
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            z = 1.5;%app.zEditField.Value + app.IncrementSpinner.Value;
            app.zEditField.Value = z;
            tp.set_point(x, y, z);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BWDButton = uibutton(app.UIFigure, 'push');
            app.BWDButton.ButtonPushedFcn = createCallbackFcn(app, @BWDButtonPushed, true);
            app.BWDButton.Text = 'BWD';
            app.FWDButton = uibutton(app.UIFigure, 'push');
            app.FWDButton.ButtonPushedFcn = createCallbackFcn(app, @FWDButtonPushed, true);
            app.FWDButton.Text = 'FWD';
            app.LEFTButton = uibutton(app.UIFigure, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = 'LEFT';
            app.RIGHTButton = uibutton(app.UIFigure, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.UICameraViewer = uiaxes(app.UIFigure);
            title(app.UICameraViewer, 'Camera Viewer')
            app.UICameraViewer.YTick = [];
            app.UICameraViewer.YTickLabel = '';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.2f';
            app.xEditField.Editable = 'off';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.2f';
            app.yEditField.Editable = 'off';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.2f';
            app.zEditField.Editable = 'off';
            app.zEditField.Value = 1.5;
            app.DPostionControllerLabel = uilabel(app.UIFigure);
            app.DPostionControllerLabel.Text = '3D Postion Controller';
            app.ForceNEditFieldLabel = uilabel(app.UIFigure);
            app.ForceNEditFieldLabel.Text = 'Force (N)';
            app.ForceNEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceNEditField.ValueDisplayFormat = '%.5f';
            app.ForceNEditField.Editable = 'off';
            app.Current3DPostionLabel = uilabel(app.UIFigure);
            app.Current3DPostionLabel.Text = 'Current 3D Postion';
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
            app.IncrementSpinnerLabel = uilabel(app.UIFigure);
            app.IncrementSpinnerLabel.Text = 'Increment';
            app.IncrementSpinner = uispinner(app.UIFigure);
            app.IncrementSpinner.Step = 0.5;
            app.IncrementSpinner.Limits = [0.5 1];
            app.IncrementSpinner.ValueDisplayFormat = '%.1f';
            app.IncrementSpinner.Editable = 'off';
            app.IncrementSpinner.Value = 0.5;
            app.OffsetNEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetNEditFieldLabel.Text = 'Offset (N)';
            app.OffsetNEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetNEditField.Limits = [0 Inf];
            app.OffsetNEditField.ValueDisplayFormat = '%.5f';
            app.OffsetNEditField.Editable = 'off';
            app.UIForceVisualizer = uiaxes(app.UIFigure);
            title(app.UIForceVisualizer, 'Force Sesnsor')
            app.UIForceVisualizer.XLim = [0 5];
            app.UIForceVisualizer.XLimMode = 'manual';
            app.UIForceVisualizer.YLim = [0 20];
            app.UIForceVisualizer.YLimMode = 'manual';
            app.UIForceVisualizer.XTick = [];
            app.UIForceVisualizer.YTick = [0 5 10 15 20];
            app.ForceNLabel = uilabel(app.UIFigure);
            app.ForceNLabel.Text = 'Force (N)';
            app.SaveGraphButton = uibutton(app.UIFigure, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ResolutionSpinnerLabel = uilabel(app.UIFigure);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.UIFigure);
            app.ResolutionSpinner.Step = 0.1;
            app.ResolutionSpinner.Limits = [0.1 1];
            app.ResolutionSpinner.ValueDisplayFormat = '%.1f';
            app.ResolutionSpinner.Editable = 'off';
            app.ResolutionSpinner.Value = 0.1;
            app.DScanSettingsLabel = uilabel(app.UIFigure);
            app.DScanSettingsLabel.Text = '3D Scan Settings';
            app.HeightzEditFieldLabel = uilabel(app.UIFigure);
            app.HeightzEditFieldLabel.Text = 'Height (z)';
            app.HeightzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightzEditField.Limits = [0 Inf];
            app.HeightzEditField.ValueDisplayFormat = '%.5f';
            app.HeightzEditField.Editable = 'off';
            app.GetHeightButton = uibutton(app.UIFigure, 'push');
            app.GetHeightButton.ButtonPushedFcn = createCallbackFcn(app, @GetHeightButtonPushed, true);
            app.GetHeightButton.Text = 'Get Height';
            app.GetGraphButton = uibutton(app.UIFigure, 'push');
            app.GetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GetGraphButtonPushed, true);
            app.GetGraphButton.Text = 'Get Graph';
            app.HeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmmEditField.ValueDisplayFormat = '%.5f';
            app.HeightmmEditField.Editable = 'off';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Force Scan Discrete', 'Force Scan Dragging', 'Z-Scan Binary', 'Z-Scan Backtrack'};
            app.MethodDropDown.Value = 'Force Scan Discrete';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TouchProbeGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end